classdef Modeling_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulatedTimesEditFieldLabel    matlab.ui.control.Label
        SimulatedTimesEditField         matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        TemperatureSimulationTab        matlab.ui.container.Tab
        TileThicknessmEditFieldLabel    matlab.ui.control.Label
        TileThicknessmEditField         matlab.ui.control.NumericEditField
        RunTemperatureSimulationButton  matlab.ui.control.Button
        MaxTemperatureReachedKEditFieldLabel  matlab.ui.control.Label
        MaxTemperatureReachedKEditField  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ShootingMethodTab               matlab.ui.container.Tab
        MaxTemperatureKEditField_2Label  matlab.ui.control.Label
        MaxTemperatureKEditField_2      matlab.ui.control.NumericEditField
        RunShootingMethodButton         matlab.ui.control.Button
        SimulationOutputEditFieldLabel  matlab.ui.control.Label
        SimulationOutputEditField       matlab.ui.control.EditField
        UIAxes3                         matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        NumberofTimestepsEditField      matlab.ui.control.NumericEditField
        NumberofSpatialStepsEditFieldLabel  matlab.ui.control.Label
        NumberofSpatialStepsEditField   matlab.ui.control.NumericEditField
        SolutionMethodDropDownLabel     matlab.ui.control.Label
        SolutionMethodDropDown          matlab.ui.control.DropDown
        ThermalConductivityWmKEditFieldLabel  matlab.ui.control.Label
        ThermalConductivityWmKEditField  matlab.ui.control.NumericEditField
        MaterialDensityLabel            matlab.ui.control.Label
        TileDensitykgm3EditField        matlab.ui.control.NumericEditField
        SpecificHeatCapacityJkgKEditFieldLabel  matlab.ui.control.Label
        SpecificHeatCapacityJkgKEditField  matlab.ui.control.NumericEditField
        TemperatureReadingDropDownLabel  matlab.ui.control.Label
        TemperatureReadingDropDown      matlab.ui.control.DropDown
    end


    properties (Access = public)
        x = []; % x coordinate vector
        t = []; % time vector
        u = []; % results matrix
        maxTemp = [];
        pos = [];


    end

    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunTemperatureSimulationButton
        function RunTemperatureSimulationButtonPushed(app, event)
            tmax = app.SimulatedTimesEditField.Value;
            nt = app.NumberofTimestepsEditField.Value;
            xmax = app.TileThicknessmEditField.Value;
            nx = app.NumberofSpatialStepsEditField.Value;
            method = app.SolutionMethodDropDown.Value;
            thermCon = app.ThermalConductivityWmKEditField.Value;
            density = app.TileDensitykgm3EditField.Value;
            specHeat = app.SpecificHeatCapacityJkgKEditField.Value;
            sensor = app.TemperatureReadingDropDown.Value;
            dt = tmax / (nt-1);

            [app.x, app.t, app.u, app.maxTemp, app.pos] = shuttle(tmax, nt, xmax, nx, method, thermCon, density, specHeat, sensor);

            app.MaxTemperatureReachedKEditField.Value = app.maxTemp;

            surf(app.UIAxes, app.x, app.t , app.u)
            shading(app.UIAxes,"interp")

            plot(app.UIAxes2, 0:dt:tmax, app.u(:, 1));
            hold(app.UIAxes2, "on")
            text(app.UIAxes2, app.pos, (app.maxTemp + 75),['Max Temp =' num2str(round(app.maxTemp)) 'K'], 'FontSize', 12)
            plot(app.UIAxes2, app.pos, app.maxTemp,'x','MarkerSize', 10)
            plot(app.UIAxes2, 0:dt:tmax, app.u(:, nx));
            hold(app.UIAxes2, "off")

        end

        % Button pushed function: RunShootingMethodButton
        function RunShootingMethodButtonPushed(app, event)
            tmax = app.SimulatedTimesEditField.Value;
            nt = app.NumberofTimestepsEditField.Value;
            nx = app.NumberofSpatialStepsEditField.Value;
            method = app.SolutionMethodDropDown.Value;
            thermCon = app.ThermalConductivityWmKEditField.Value;
            density = app.TileDensitykgm3EditField.Value;
            specHeat = app.SpecificHeatCapacityJkgKEditField.Value;
            sensor = app.TemperatureReadingDropDown.Value;

            intTemp = app.MaxTemperatureKEditField_2.Value;

            [xmax, guess, result, idx, app.pos] = shootingxmax(intTemp,tmax, nt, nx, method, thermCon, density, specHeat, sensor);

            plot(app.UIAxes3,guess, result, '-x')
            hold(app.UIAxes3, "on")
            text(app.UIAxes3,xmax,(intTemp + 10), ['Tile Thickness =' num2str(round(xmax,3,"significant")) 'm'], 'FontSize', 12)
            plot(app.UIAxes3,xmax,intTemp,'o','MarkerSize',10)

            for n = 1:max(idx)
                text(app.UIAxes3,guess(n), (result(n) - 5), num2str(idx(n)))
            end

            hold(app.UIAxes3, "off")

            if app.pos == tmax 
                app.SimulationOutputEditField.Value = ('Maximum Temperature Occured at Final Timestep, Increase Simulated Time');
            else
                app.SimulationOutputEditField.Value = (['Tile Thickness for ' num2str(intTemp) 'K Max Internal Temperature is ' num2str(round((xmax * 1e03),3,"significant")) 'mm']);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulatedTimesEditFieldLabel = uilabel(app.UIFigure);
            app.SimulatedTimesEditFieldLabel.Text = {'Simulated Time (s)'; ''};
            app.SimulatedTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulatedTimesEditField.Value = 4000;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TemperatureSimulationTab = uitab(app.TabGroup);
            app.TemperatureSimulationTab.Title = 'Temperature Simulation';
            app.TileThicknessmEditFieldLabel = uilabel(app.TemperatureSimulationTab);
            app.TileThicknessmEditFieldLabel.Text = 'Tile Thickness (m)';
            app.TileThicknessmEditField = uieditfield(app.TemperatureSimulationTab, 'numeric');
            app.TileThicknessmEditField.Value = 0.05;
            app.RunTemperatureSimulationButton = uibutton(app.TemperatureSimulationTab, 'push');
            app.RunTemperatureSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunTemperatureSimulationButtonPushed, true);
            app.RunTemperatureSimulationButton.Text = 'Run Temperature Simulation';
            app.MaxTemperatureReachedKEditFieldLabel = uilabel(app.TemperatureSimulationTab);
            app.MaxTemperatureReachedKEditFieldLabel.Text = 'Max Temperature Reached (K)';
            app.MaxTemperatureReachedKEditField = uieditfield(app.TemperatureSimulationTab, 'numeric');
            app.UIAxes = uiaxes(app.TemperatureSimulationTab);
            title(app.UIAxes, '3D Transient Heating')
            xlabel(app.UIAxes, 'Distance (m)')
            ylabel(app.UIAxes, 'Time  (s)')
            zlabel(app.UIAxes, 'Temperature (K)')
            app.UIAxes2 = uiaxes(app.TemperatureSimulationTab);
            title(app.UIAxes2, 'Tile Face Heating')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Temperature (K)')
            zlabel(app.UIAxes2, 'Z')
            app.ShootingMethodTab = uitab(app.TabGroup);
            app.ShootingMethodTab.Title = 'Shooting Method';
            app.MaxTemperatureKEditField_2Label = uilabel(app.ShootingMethodTab);
            app.MaxTemperatureKEditField_2Label.Text = 'Max Temperature (K)';
            app.MaxTemperatureKEditField_2 = uieditfield(app.ShootingMethodTab, 'numeric');
            app.MaxTemperatureKEditField_2.Value = 423;
            app.RunShootingMethodButton = uibutton(app.ShootingMethodTab, 'push');
            app.RunShootingMethodButton.ButtonPushedFcn = createCallbackFcn(app, @RunShootingMethodButtonPushed, true);
            app.RunShootingMethodButton.Text = 'Run Shooting Method';
            app.SimulationOutputEditFieldLabel = uilabel(app.ShootingMethodTab);
            app.SimulationOutputEditFieldLabel.Text = 'Simulation Output';
            app.SimulationOutputEditField = uieditfield(app.ShootingMethodTab, 'text');
            app.UIAxes3 = uiaxes(app.ShootingMethodTab);
            title(app.UIAxes3, 'Shooting Method Guesses')
            xlabel(app.UIAxes3, 'Tile Thickness (m)')
            ylabel(app.UIAxes3, 'Max Temperature (K)')
            zlabel(app.UIAxes3, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Number of Timesteps';
            app.NumberofTimestepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofTimestepsEditField.Value = 1001;
            app.NumberofSpatialStepsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSpatialStepsEditFieldLabel.Text = 'Number of Spatial Steps';
            app.NumberofSpatialStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSpatialStepsEditField.Value = 21;
            app.SolutionMethodDropDownLabel = uilabel(app.UIFigure);
            app.SolutionMethodDropDownLabel.Text = 'Solution Method';
            app.SolutionMethodDropDown = uidropdown(app.UIFigure);
            app.SolutionMethodDropDown.Items = {'Forward', 'Backward', 'Crank-Nicolson', 'Dufort-Frankel'};
            app.SolutionMethodDropDown.Value = 'Forward';
            app.ThermalConductivityWmKEditFieldLabel = uilabel(app.UIFigure);
            app.ThermalConductivityWmKEditFieldLabel.Text = 'Thermal Conductivity (W/m.K)';
            app.ThermalConductivityWmKEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThermalConductivityWmKEditField.Value = 0.0577;
            app.MaterialDensityLabel = uilabel(app.UIFigure);
            app.MaterialDensityLabel.Text = 'Tile Density (kg/m^3)';
            app.TileDensitykgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.TileDensitykgm3EditField.Value = 144;
            app.SpecificHeatCapacityJkgKEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificHeatCapacityJkgKEditFieldLabel.Text = 'Specific Heat Capacity (J/kg.K)';
            app.SpecificHeatCapacityJkgKEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificHeatCapacityJkgKEditField.Value = 1262;
            app.TemperatureReadingDropDownLabel = uilabel(app.UIFigure);
            app.TemperatureReadingDropDownLabel.Text = 'Temperature Reading';
            app.TemperatureReadingDropDown = uidropdown(app.UIFigure);
            app.TemperatureReadingDropDown.Items = {'Sensor 1', 'Sensor 2', 'Sensor 3', 'Sensor 4'};
            app.TemperatureReadingDropDown.Value = 'Sensor 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modeling_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end